classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DatabaseBuilderTab            matlab.ui.container.Tab
        SaveDatabaseButton            matlab.ui.control.Button
        SaveWordButton                matlab.ui.control.Button
        RecordButton                  matlab.ui.control.Button
        WordNameField                 matlab.ui.control.EditField
        EnterWordNameLabel            matlab.ui.control.Label
        SpeakerDropDown               matlab.ui.control.DropDown
        SelectSpeakerDropDownLabel    matlab.ui.control.Label
        SpeakerandWordSetupLabel      matlab.ui.control.Label
        FFTAxes1                      matlab.ui.control.UIAxes
        WaveformAxes                  matlab.ui.control.UIAxes
        FFTVisualizationTab           matlab.ui.container.Tab
        PlotFFTButton                 matlab.ui.control.Button
        VisWordDropDown               matlab.ui.control.DropDown
        SelectWordDropDownLabel       matlab.ui.control.Label
        VisSpeakerDropDown            matlab.ui.control.DropDown
        SelectSpeakerDropDown_2Label  matlab.ui.control.Label
        FFTAxes2                      matlab.ui.control.UIAxes
        IdentificationTab             matlab.ui.container.Tab
        LoadDatabaseButton            matlab.ui.control.Button
        PlayTestWordButton            matlab.ui.control.Button
        AnalyzeButton                 matlab.ui.control.Button
        RecordTestButton              matlab.ui.control.Button
        IdentifiedSpeakerLabel        matlab.ui.control.Label
        IdentifiedWordLabel           matlab.ui.control.Label
        TestNewSpeakerLabel           matlab.ui.control.Label
        MatchFFTAxes                  matlab.ui.control.UIAxes
        TestFFTAxes                   matlab.ui.control.UIAxes
        ExitTab                       matlab.ui.container.Tab
        EXITButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        recObj = audiorecorder(8000,16,1)
        recAudioData
        db = {}       % database structure
        testData      % test recording
        testFeature
        Fs = 8000
    end
    
    methods (Access = private)
        
        function features = computeWordFeatures(app, y, Fs)
            % Divide word into sections and extract frequency-band features
            y = y - mean(y);            % remove DC offset
            numSections = 4;            % divide word into 4 parts
            L = length(y);
            secLen = floor(L / numSections);

            numBands = 5;               % frequency bands per section
            features = zeros(numSections, numBands);

            for s = 1:numSections
                startIdx = (s-1)*secLen + 1;
                endIdx = min(s*secLen, L);
                segment = y(startIdx:endIdx);

                % Blackman window
                w = blackman(length(segment));
                segment = segment .* w;

                % FFT and magnitude
                Y = abs(fft(segment));
                Y = Y(1:floor(length(Y)/2)); % keep positive frequencies only

                % Divide spectrum into bands and find avg energy per band
                bandSize = floor(length(Y)/numBands);
                for b = 1:numBands
                    range = (b-1)*bandSize + 1 : b*bandSize;
                    features(s,b) = mean(Y(range));
                end
            end

            features = features ./ max(features(:));

        end
        
        function IdentifyWordAndSpeaker(app)
            if isempty(app.testFeature)
                uialert(app.UIFigure, 'Please analyze the test word first.', 'Error');
                return;
            end
            if isempty(app.db)
                uialert(app.UIFigure, 'Please load the database first.', 'Error');
                return;
            end

            if ~isstruct(app.db)
                uialert(app.UIFigure, 'Database format invalid. Please rebuild.', 'Error');
                return;
            end

            % Setup comparison
            testFeat = app.testFeature(:);
            bestDistance = inf;
            bestSpeaker = '';
            bestWord = '';
            bestFeat = [];

            speakers = fieldnames(app.db);
            for s = 1:length(speakers)
                sp = speakers{s};
                words = fieldnames(app.db.(sp));
                for w = 1:length(words)
                    wd = words{w};
                    samples = app.db.(sp).(wd).samples;

                    allSamples = [];
                    for k = 1:length(samples)
                        sampleVec = samples{k}(:);
                        sampleVec = sampleVec ./ max(sampleVec); 
                        minLen = min(length(sampleVec), length(testFeat));
                        allSamples(:,k) = sampleVec(1:minLen);
                    end
                    refMean = mean(allSamples, 2); % avg across all takes

                    r = corrcoef(refMean, testFeat(1:length(refMean)));
                    if numel(r) > 1
                        d = 1 - r(1,2); 
                    else
                        d = inf;
                    end

                    if d < bestDistance
                        bestDistance = d;
                        bestSpeaker = sp;
                        bestWord = wd;
                        bestFeat = refMean;
                    end
                end
            end

            if isempty(bestWord)
                app.IdentifiedWordLabel.Text = 'Identified Word: No match';
                app.IdentifiedSpeakerLabel.Text = 'Identified Speaker: -';
                cla(app.MatchFFTAxes);
                return;
            end

            app.IdentifiedWordLabel.Text = ['Identified Word: ' bestWord];
            app.IdentifiedSpeakerLabel.Text = ['Identified Speaker: ' bestSpeaker];

            cla(app.MatchFFTAxes);
            plot(app.MatchFFTAxes, bestFeat, 'r');
            title(app.MatchFFTAxes, sprintf('Closest Match: %s (%s)', bestWord, bestSpeaker));
            xlabel(app.MatchFFTAxes, 'Feature Index');
            ylabel(app.MatchFFTAxes, 'Magnitude');
            grid(app.MatchFFTAxes, 'on');

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            recordTime = 2; 

            msgbox('Recording... Speak now!','modal');
            recordblocking(app.recObj, recordTime);

            app.recAudioData = getaudiodata(app.recObj);

            cla(app.WaveformAxes);
            t = (0:length(app.recAudioData)-1)/app.Fs;
            plot(app.WaveformAxes, t, app.recAudioData);
            title(app.WaveformAxes, 'Recorded Word');
            xlabel(app.WaveformAxes, 'Time (s)');
            ylabel(app.WaveformAxes, 'Amplitude');

            msgbox('Recording complete.');  
        end

        % Button pushed function: SaveWordButton
        function SaveWordButtonPushed(app, event)
            % Check that something was recorded
            if isempty(app.recAudioData)
                uialert(app.UIFigure, 'Please record a word first.', 'Error');
                return;
            end

            % Get user info
            speaker = app.SpeakerDropDown.Value;
            wordName = strtrim(app.WordNameField.Value);
            y = app.recAudioData;

            y = y - mean(y);                    % remove DC offset
            y = smoothdata(y, 'movmean', 10);   % optional smoothing

            threshold = 0.05 * max(abs(y));     % 5% of max amplitude
            indices = find(abs(y) > threshold);

            if isempty(indices)
                uialert(app.UIFigure,'No speech detected! Try again.','Warning');
                return;
            end

            y = y(indices(1):indices(end));     % keep only word portion

            cla(app.WaveformAxes);
            t = (0:length(y)-1)/app.Fs;
            plot(app.WaveformAxes, t, y);
            title(app.WaveformAxes, 'Isolated Word Signal');
            xlabel(app.WaveformAxes, 'Time (s)');
            ylabel(app.WaveformAxes, 'Amplitude');

            features = computeWordFeatures(app, y, app.Fs);

            cla(app.FFTAxes1);
            plot(app.FFTAxes1, features(:), '-o');
            title(app.FFTAxes1, 'FFT Feature Magnitudes');
            xlabel(app.FFTAxes1, 'Feature Index');
            ylabel(app.FFTAxes1, 'Magnitude');

            if ~isfield(app.db, speaker)
                app.db.(speaker) = struct();
            end
            if ~isfield(app.db.(speaker), wordName)
                app.db.(speaker).(wordName).samples = {};
            end

            app.db.(speaker).(wordName).samples{end+1} = features;

            folderName = 'RecordedWords';
            if ~exist(folderName, 'dir')
                mkdir(folderName);
            end

            existingTakes = length(app.db.(speaker).(wordName).samples);
            fileName = sprintf('%s_%s_take%d.wav', speaker, wordName, existingTakes);
            filePath = fullfile(folderName, fileName);

            audiowrite(filePath, y, app.Fs);
            fprintf('Saved audio file: %s\n', filePath);

            msgbox(sprintf('Saved "%s" for %s.', wordName, speaker));            
        end

        % Button pushed function: SaveDatabaseButton
        function SaveDatabaseButtonPushed(app, event)
                if isempty(app.db)
                    uialert(app.UIFigure,'No database data to save!','Error');
                    return;
                end
                db = app.db;  
                save('wordDatabase.mat', 'db');
                msgbox('Database saved as wordDatabase.mat');
        end

        % Button pushed function: PlotFFTButton
        function PlotFFTButtonPushed(app, event)
            % Check if database exists
            if isempty(app.db)
                try
                    tmp = load('wordDatabase.mat');
                    app.db = tmp.db;
                catch
                    uialert(app.UIFigure, 'Please record and save a database first.', 'Error');
                    return;
                end
            end

            speaker = app.VisSpeakerDropDown.Value;
            wordName = app.VisWordDropDown.Value;

            if ~isfield(app.db, speaker)
                uialert(app.UIFigure, 'Selected speaker not found in database.', 'Error');
                return;
            end
            if ~isfield(app.db.(speaker), wordName)
                uialert(app.UIFigure, 'Selected word not found for this speaker.', 'Error');
                return;
            end

            sample = app.db.(speaker).(wordName).samples{1};

            cla(app.FFTAxes2);
            plot(app.FFTAxes2, sample(:), '-o');
            title(app.FFTAxes2, sprintf('FFT Features: %s - %s', speaker, wordName));
            xlabel(app.FFTAxes2, 'Feature Index');
            ylabel(app.FFTAxes2, 'Magnitude');
            grid(app.FFTAxes2, 'on');
        end

        % Value changed function: VisSpeakerDropDown
        function VisSpeakerDropDownValueChanged(app, event)
            speaker = app.VisSpeakerDropDown.Value;
            if isfield(app.db, speaker)
                app.VisWordDropDown.Items = fieldnames(app.db.(speaker));
            else
                app.VisWordDropDown.Items = {};
            end  
        end

        % Button pushed function: RecordTestButton
        function RecordTestButtonPushed(app, event)
            recordTime = 2; % seconds
            uialert(app.UIFigure, 'Recording will start now for 2 seconds. Speak one clear word.', 'Recording');

            recordblocking(app.recObj, recordTime);
            y = getaudiodata(app.recObj);

            app.testData = y;

            Fs = app.Fs;
            t = (0:length(y)-1)/Fs;
            cla(app.TestFFTAxes);
            plot(app.TestFFTAxes, t, y);
            title(app.TestFFTAxes, 'Recorded Test Word (Time Domain)');
            xlabel(app.TestFFTAxes, 'Time (s)');
            ylabel(app.TestFFTAxes, 'Amplitude');
            grid(app.TestFFTAxes, 'on');

            app.IdentifiedWordLabel.Text = 'Identified Word: -';
            app.IdentifiedSpeakerLabel.Text = 'Identified Speaker: -';

            audiowrite('testWord.wav', y, Fs);
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if isempty(app.testData)
                uialert(app.UIFigure, 'Please record a test word first.', 'Error');
                return;
            end

            y = app.testData;
            Fs = app.Fs;

            app.testFeature = computeWordFeatures(app, y, Fs);

            flatFeat = app.testFeature(:);
            cla(app.TestFFTAxes);
            plot(app.TestFFTAxes, flatFeat, 'b');
            title(app.TestFFTAxes, 'Test Word Features');
            xlabel(app.TestFFTAxes, 'Feature Index');
            ylabel(app.TestFFTAxes, 'Magnitude');
            grid(app.TestFFTAxes, 'on');

            IdentifyWordAndSpeaker(app);

            app.testFeature = app.testFeature ./ max(app.testFeature(:));
        end

        % Button pushed function: LoadDatabaseButton
        function LoadDatabaseButtonPushed(app, event)
            try
                tmp = load('wordDatabase.mat');
                app.db = tmp.db;
                uialert(app.UIFigure, 'Database successfully loaded.', 'Success');
            catch
                uialert(app.UIFigure, 'Could not find "wordDatabase.mat". Please build database first.', 'Error');
            end
        end

        % Button pushed function: PlayTestWordButton
        function PlayTestWordButtonPushed(app, event)
            if isempty(app.testData)
                uialert(app.UIFigure, 'Please record a test word first.', 'Error');
                return;
            end

            sound(app.testData, app.Fs);
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
                selection = uiconfirm(app.UIFigure, ...
                'Do you want to save the current database before exiting?', ...
                'Exit Confirmation', ...
                'Options', {'Save & Exit','Exit Without Saving','Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3);

            switch selection
                case 'Save & Exit'
                    if ~isempty(app.db)
                        db = app.db;
                        save('wordDatabase.mat','db');
                    end
                    delete(app.UIFigure);   % closes the app
                case 'Exit Without Saving'
                    delete(app.UIFigure);
                case 'Cancel'
                    return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatabaseBuilderTab = uitab(app.TabGroup);
            app.DatabaseBuilderTab.Title = 'Database Builder';
            app.WaveformAxes = uiaxes(app.DatabaseBuilderTab);
            title(app.WaveformAxes, 'Plot of recorded waveform')
            xlabel(app.WaveformAxes, 'X')
            ylabel(app.WaveformAxes, 'Y')
            zlabel(app.WaveformAxes, 'Z')
            app.FFTAxes1 = uiaxes(app.DatabaseBuilderTab);
            title(app.FFTAxes1, 'Plot of FFT magnitude')
            xlabel(app.FFTAxes1, 'X')
            ylabel(app.FFTAxes1, 'Y')
            zlabel(app.FFTAxes1, 'Z')
            app.SpeakerandWordSetupLabel = uilabel(app.DatabaseBuilderTab);
            app.SpeakerandWordSetupLabel.Text = 'Speaker and Word Setup';
            app.SelectSpeakerDropDownLabel = uilabel(app.DatabaseBuilderTab);
            app.SelectSpeakerDropDownLabel.Text = 'Select Speaker';
            app.SpeakerDropDown = uidropdown(app.DatabaseBuilderTab);
            app.SpeakerDropDown.Items = {'Speaker1', 'Speaker2'};
            app.SpeakerDropDown.Value = 'Speaker1';
            app.EnterWordNameLabel = uilabel(app.DatabaseBuilderTab);
            app.EnterWordNameLabel.Text = 'Enter Word Name:';
            app.WordNameField = uieditfield(app.DatabaseBuilderTab, 'text');
            app.RecordButton = uibutton(app.DatabaseBuilderTab, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record Word';
            app.SaveWordButton = uibutton(app.DatabaseBuilderTab, 'push');
            app.SaveWordButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWordButtonPushed, true);
            app.SaveWordButton.Text = '💾 Save to Database 💾';
            app.SaveDatabaseButton = uibutton(app.DatabaseBuilderTab, 'push');
            app.SaveDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDatabaseButtonPushed, true);
            app.SaveDatabaseButton.Text = '📂 Save Database 📂';
            app.FFTVisualizationTab = uitab(app.TabGroup);
            app.FFTVisualizationTab.Title = 'FFT Visualization';
            app.FFTAxes2 = uiaxes(app.FFTVisualizationTab);
            title(app.FFTAxes2, 'FFT Plot')
            xlabel(app.FFTAxes2, 'X')
            ylabel(app.FFTAxes2, 'Y')
            zlabel(app.FFTAxes2, 'Z')
            app.SelectSpeakerDropDown_2Label = uilabel(app.FFTVisualizationTab);
            app.SelectSpeakerDropDown_2Label.Text = 'Select Speaker';
            app.VisSpeakerDropDown = uidropdown(app.FFTVisualizationTab);
            app.VisSpeakerDropDown.Items = {'Speaker1', 'Speaker2'};
            app.VisSpeakerDropDown.ValueChangedFcn = createCallbackFcn(app, @VisSpeakerDropDownValueChanged, true);
            app.VisSpeakerDropDown.Value = 'Speaker1';
            app.SelectWordDropDownLabel = uilabel(app.FFTVisualizationTab);
            app.SelectWordDropDownLabel.Text = 'Select Word';
            app.VisWordDropDown = uidropdown(app.FFTVisualizationTab);
            app.VisWordDropDown.Items = {};
            app.VisWordDropDown.Value = {};
            app.PlotFFTButton = uibutton(app.FFTVisualizationTab, 'push');
            app.PlotFFTButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFFTButtonPushed, true);
            app.PlotFFTButton.Text = '🔍Plot FFT 🔍';
            app.IdentificationTab = uitab(app.TabGroup);
            app.IdentificationTab.Title = 'Word & Speaker Identification';
            app.TestFFTAxes = uiaxes(app.IdentificationTab);
            title(app.TestFFTAxes, 'Test FFT Plot')
            xlabel(app.TestFFTAxes, 'X')
            ylabel(app.TestFFTAxes, 'Y')
            zlabel(app.TestFFTAxes, 'Z')
            app.MatchFFTAxes = uiaxes(app.IdentificationTab);
            title(app.MatchFFTAxes, 'Match FFT Plot')
            xlabel(app.MatchFFTAxes, 'X')
            ylabel(app.MatchFFTAxes, 'Y')
            zlabel(app.MatchFFTAxes, 'Z')
            app.TestNewSpeakerLabel = uilabel(app.IdentificationTab);
            app.TestNewSpeakerLabel.Text = 'Test New Speaker';
            app.IdentifiedWordLabel = uilabel(app.IdentificationTab);
            app.IdentifiedWordLabel.Text = 'Identified Word:';
            app.IdentifiedSpeakerLabel = uilabel(app.IdentificationTab);
            app.IdentifiedSpeakerLabel.Text = 'Identified Speaker:';
            app.RecordTestButton = uibutton(app.IdentificationTab, 'push');
            app.RecordTestButton.ButtonPushedFcn = createCallbackFcn(app, @RecordTestButtonPushed, true);
            app.RecordTestButton.Text = 'Record Test Word';
            app.AnalyzeButton = uibutton(app.IdentificationTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze Word';
            app.PlayTestWordButton = uibutton(app.IdentificationTab, 'push');
            app.PlayTestWordButton.ButtonPushedFcn = createCallbackFcn(app, @PlayTestWordButtonPushed, true);
            app.PlayTestWordButton.Text = 'Play Test Word';
            app.LoadDatabaseButton = uibutton(app.IdentificationTab, 'push');
            app.LoadDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatabaseButtonPushed, true);
            app.LoadDatabaseButton.Text = 'Load Database';
            app.ExitTab = uitab(app.TabGroup);
            app.ExitTab.Title = 'Exit';
            app.EXITButton = uibutton(app.ExitTab, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end